classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label_5         matlab.ui.control.Label
        Button          matlab.ui.control.Button
        EditField_2     matlab.ui.control.EditField
        Label_4         matlab.ui.control.Label
        Label_3         matlab.ui.control.Label
        Label_2         matlab.ui.control.Label
        LoadFileButton  matlab.ui.control.Button
        Label           matlab.ui.control.Label
        EditField       matlab.ui.control.NumericEditField
        OriginSeries    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T % Отметки времени измерений сигнала
        F % Исходный сигнал
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile("*.csv", "Выберите csv-файл с данными");
            T = readtable(strcat(path, file));
            app.F = T.RCORR_P;
            app.T = T.start_date_time;
            app.OriginSeries.Visible = true;
            plot(app.OriginSeries, app.T, app.F, 'b-');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Инструмент обработки и анализа сигналов геофизического мониторинга';
            app.OriginSeries = uiaxes(app.UIFigure);
            title(app.OriginSeries, 'Исходный сигнал')
            app.OriginSeries.Visible = 'off';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Длина окна';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Загрузить файл';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Шаг 1: Загрузите данные';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Шаг 2: Укажите параметры метода';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Используемые компоненты';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Placeholder = '1, 2, 3';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = 'Начать обработку';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Шаг3 : Запуск метода';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end